classdef FPGASigGen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SinSwitchLabel                 matlab.ui.control.Label
        SinSwitch                      matlab.ui.control.ToggleSwitch
        ConnectBoardButton             matlab.ui.control.StateButton
        FrequencyEditFieldLabel        matlab.ui.control.Label
        FrequencyEditField             matlab.ui.control.NumericEditField
        VolumeKnobLabel                matlab.ui.control.Label
        VolumeKnob                     matlab.ui.control.Knob
        CoarsekHzSliderLabel           matlab.ui.control.Label
        CoarsekHzSlider                matlab.ui.control.Slider
        FineTuneHzSliderLabel          matlab.ui.control.Label
        FineTuneHzSlider               matlab.ui.control.Slider
        FPGABoardSignalGeneratorLabel  matlab.ui.control.Label
        TriangleSwitchLabel            matlab.ui.control.Label
        TriangleSwitch                 matlab.ui.control.ToggleSwitch
        SquareSwitchLabel              matlab.ui.control.Label
        SquareSwitch                   matlab.ui.control.ToggleSwitch
    end

    
    properties (Access = private)
        axiMaster;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ConnectBoardButton
        function ConnectBoardButtonValueChanged(app, event)
            if app.ConnectBoardButton.Value;
                app.ConnectBoardButton.Text = "Connected"; drawnow;
                
                axiMaster = aximaster('Intel', 'interface', 'JTAG');
                app.axiMaster = axiMaster;
                writememory(app.axiMaster, '0x108', 1)
                % Switch Update
                if strcmp(app.SinSwitch.Value, 'On')
                    writememory(app.axiMaster, '0x11C', 1)   % Sin
                else
                    writememory(app.axiMaster, '0x11C', 0)   % Sin
                end
                if strcmp(app.TriangleSwitch.Value, 'On')
                    writememory(app.axiMaster, '0x118', 1)   % Tri
                else
                    writememory(app.axiMaster, '0x118', 0)   % Tri
                end
                if strcmp(app.SquareSwitch.Value, 'On')
                    writememory(app.axiMaster, '0x114', 1)   % Sqr
                else
                    writememory(app.axiMaster, '0x114', 0)   % Sqr
                end
                writememory(app.axiMaster, '0x120', 1)   % Strobe
                
            else
                try
                    writememory(app.axiMaster, '0x108', 0)
                    release(app.axiMaster)
                    delete(app.axiMaster)
                end
                app.ConnectBoardButton.Text = "Connect Board"; drawnow;
            end
            
        end

        % Value changed function: SinSwitch
        function SinSwitchValueChanged(app, event)
            if strcmp(app.SinSwitch.Value, 'On')
                writememory(app.axiMaster, '0x11C', 1)   % Sin
            else
                writememory(app.axiMaster, '0x11C', 0)   % Sin
            end
            
            %             writememory(app.axiMaster, '0x11C', 1)   % Sin
            %             writememory(app.axiMaster, '0x118', 1)   % Tri
            %             writememory(app.axiMaster, '0x114', 1)   % Sqr
            writememory(app.axiMaster, '0x120', 1)   % Strobe
            
        end

        % Value changed function: TriangleSwitch
        function TriangleSwitchValueChanged(app, event)
            if strcmp(app.TriangleSwitch.Value, 'On')
                writememory(app.axiMaster, '0x118', 1)   % Tri
            else
                writememory(app.axiMaster, '0x118', 0)   % Tri
            end
            %             writememory(app.axiMaster, '0x11C', 1)   % Sin
            %             writememory(app.axiMaster, '0x118', 1)   % Tri
            %             writememory(app.axiMaster, '0x114', 1)   % Sqr
            writememory(app.axiMaster, '0x120', 1)   % Strobe
            
        end

        % Value changed function: SquareSwitch
        function SquareSwitchValueChanged(app, event)
            if strcmp(app.SquareSwitch.Value, 'On')
                writememory(app.axiMaster, '0x114', 1)   % Sqr
            else
                writememory(app.axiMaster, '0x114', 0)   % Sqr
            end
            %             writememory(app.axiMaster, '0x11C', 1)   % Sin
            %             writememory(app.axiMaster, '0x118', 1)   % Tri
            %             writememory(app.axiMaster, '0x114', 1)   % Sqr
            writememory(app.axiMaster, '0x120', 1)   % Strobe
            
        end

        % Value changed function: VolumeKnob
        function VolumeKnobValueChanged(app, event)
            value = floor(app.VolumeKnob.Value);
            writememory(app.axiMaster, '0x100', uint32(value));
            app.VolumeKnob.Value = value;
            
        end

        % Value changed function: FrequencyEditField
        function FrequencyEditFieldValueChanged(app, event)
            value = app.FrequencyEditField.Value;
            coarseValue = floor(1e-3*value);
            app.CoarsekHzSlider.Value = coarseValue;
            fineValue = value-1e3*coarseValue;
            app.FineTuneHzSlider.Value = fineValue;
            writememory(app.axiMaster, '0x104', value)
        end

        % Value changed function: CoarsekHzSlider
        function CoarsekHzSliderValueChanged(app, event)
            value = floor(app.CoarsekHzSlider.Value);
            app.CoarsekHzSlider.Value = value;
            app.FrequencyEditField.Value = app.FineTuneHzSlider.Value + 1e3*app.CoarsekHzSlider.Value;
            writememory(app.axiMaster, '0x104', app.FrequencyEditField.Value)
            
        end

        % Value changed function: FineTuneHzSlider
        function FineTuneHzSliderValueChanged(app, event)
            value = floor(app.FineTuneHzSlider.Value);
            app.FineTuneHzSlider.Value = value;
            app.FrequencyEditField.Value = app.FineTuneHzSlider.Value + 1e3*app.CoarsekHzSlider.Value;
            writememory(app.axiMaster, '0x104', app.FrequencyEditField.Value)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SinSwitchLabel = uilabel(app.UIFigure);
            app.SinSwitchLabel.Text = 'Sin';
            app.SinSwitch = uiswitch(app.UIFigure, 'toggle');
            app.SinSwitch.ValueChangedFcn = createCallbackFcn(app, @SinSwitchValueChanged, true);
            app.ConnectBoardButton = uibutton(app.UIFigure, 'state');
            app.ConnectBoardButton.ValueChangedFcn = createCallbackFcn(app, @ConnectBoardButtonValueChanged, true);
            app.ConnectBoardButton.Text = 'Connect Board';
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyEditFieldValueChanged, true);
            app.VolumeKnobLabel = uilabel(app.UIFigure);
            app.VolumeKnobLabel.Text = 'Volume';
            app.VolumeKnob = uiknob(app.UIFigure, 'continuous');
            app.VolumeKnob.Limits = [0 15];
            app.VolumeKnob.ValueChangedFcn = createCallbackFcn(app, @VolumeKnobValueChanged, true);
            app.CoarsekHzSliderLabel = uilabel(app.UIFigure);
            app.CoarsekHzSliderLabel.Text = 'Coarse(kHz)';
            app.CoarsekHzSlider = uislider(app.UIFigure);
            app.CoarsekHzSlider.Limits = [0 20];
            app.CoarsekHzSlider.ValueChangedFcn = createCallbackFcn(app, @CoarsekHzSliderValueChanged, true);
            app.CoarsekHzSlider.MinorTicks = [];
            app.FineTuneHzSliderLabel = uilabel(app.UIFigure);
            app.FineTuneHzSliderLabel.Text = 'Fine Tune (Hz)';
            app.FineTuneHzSlider = uislider(app.UIFigure);
            app.FineTuneHzSlider.Limits = [0 1000];
            app.FineTuneHzSlider.MajorTicks = [0 100 200 300 400 500 600 700 800 900 1000];
            app.FineTuneHzSlider.ValueChangedFcn = createCallbackFcn(app, @FineTuneHzSliderValueChanged, true);
            app.FPGABoardSignalGeneratorLabel = uilabel(app.UIFigure);
            app.FPGABoardSignalGeneratorLabel.Text = 'FPGA Board Signal Generator';
            app.TriangleSwitchLabel = uilabel(app.UIFigure);
            app.TriangleSwitchLabel.Text = 'Triangle';
            app.TriangleSwitch = uiswitch(app.UIFigure, 'toggle');
            app.TriangleSwitch.ValueChangedFcn = createCallbackFcn(app, @TriangleSwitchValueChanged, true);
            app.SquareSwitchLabel = uilabel(app.UIFigure);
            app.SquareSwitchLabel.Text = 'Square';
            app.SquareSwitch = uiswitch(app.UIFigure, 'toggle');
            app.SquareSwitch.ValueChangedFcn = createCallbackFcn(app, @SquareSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FPGASigGen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end